classdef Pulse_Modulation_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PulseModulationGUILabel        matlab.ui.control.Label
        NoIntervalsEditField           matlab.ui.control.NumericEditField
        NoIntervalsEditFieldLabel      matlab.ui.control.Label
        TimeVectorEditField            matlab.ui.control.EditField
        TimeVectorEditFieldLabel       matlab.ui.control.Label
        SamplingTimeTsEditField        matlab.ui.control.NumericEditField
        SamplingTimeTsLabel            matlab.ui.control.Label
        ModulationSchemaDropDown       matlab.ui.control.DropDown
        ModulationSchemaDropDownLabel  matlab.ui.control.Label
        PulsetimeTEditField            matlab.ui.control.NumericEditField
        TLabel                         matlab.ui.control.Label
        AmplitudeAEditField            matlab.ui.control.NumericEditField
        AmplitudeAEditFieldLabel       matlab.ui.control.Label
        MessageSignalmtEditField       matlab.ui.control.EditField
        MessageSignalmtEditFieldLabel  matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
    end


    methods (Access = private)

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Modulation %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % Function for generating PAM modulation signal
        function p= pam_modulation(~, t, m, Ts, T)
            % Calculate carrier frequency
            fc = 1/Ts;

            % Generate square wave carrier signal
            ct = square(2 * pi * fc * t, T / Ts * 100);

            % Modulate message signal with carrier
            p = m .*(mod(t, T) < Ts/2) .* ct;
        end


        % Function for generating flat-top PAM modulation signal
        function p= flat_top_pam_modulation(~, t, m, Ts, T)
            % Calculate carrier frequency
            fc = 1/Ts;

            % Generate square wave carrier signal
            ct = square(2 * pi * fc * t, T / Ts * 100);

            % Remove negative values from carrier signal
            ct(ct < 0) = 0;

            % Calculate number of samples per period for the carrier
            samplesPerPeriod = round((Ts) * numel(t) / (t(end) - t(1) + t(2) - t(1))); %%%%%%%%%%% REVIEW THIS LINE %%%%%%%%%%%%%%%%
            samplesInOnPeriod = round(Ts * numel(t) / (t(end) - t(1) + t(2) - t(1)));

            % Generate the flat-top PAM signal
            for i= 1:samplesPerPeriod:numel(t)
                endIndex = i + samplesInOnPeriod -1;
                if endIndex > numel(t)
                    endIndex = numel(t);
                end
                p(i:endIndex) = m(i);
            end

            % Modulate message signal with carrier
            p = p .* ct;
        end


        % Function for generating PWM modulation signal
        function p = pwm_modulation(~, t, m, A, Ts)
            % Calculate sampling rate
            n = 1 / (t(2) - t(1));
            step = round(Ts * n);

            % Calculate minimum and maximum pulse widths
            min_width = 0.1 * Ts;
            max_width = Ts - min_width;

            % Initialize PWM signal
            pwm = zeros(1, length(t));

            % Perform PWM modulation
            for i = 1:step:length(t)
                % Calculate pulse width based on modulating signal amplitude
                pulse_width = min_width + ((max_width - min_width) * (m(i) - min(m)) / (max(m) - min(m)));

                % Set PWM pulse high if modulating signal exceeds carrier signal
                pulse_end = min(i + round(pulse_width * n), length(t));  % Ensure pulse_end does not exceed length(t)
                pwm(i:pulse_end) = 1; % Set PWM pulse high for calculated pulse width
            end

            % Scale PWM signal by amplitude
            p = A * pwm;
        end


        % Function for generating PPM modulation signal
        function p= ppm_modulation(~, t, m, Ts, A, T)
            % Calculate sampling rate
            n = 1/(t(2) - t(1));
            step = round(Ts * n);

            % Calculate minimum and maximum pulse widths
            min_width = 0.1 * Ts;
            max_width = Ts - min_width;

            % Initialize PWM signal
            pwm = zeros(1, length(t));

            % Perform PWM modulation
            for i = 1:step:length(t)
                % Calculate pulse width based on modulating signal amplitude
                pulse_width = min_width + ((max_width - min_width) * (m(i) - min(m)) / (max(m) - min(m)));

                % Set PWM pulse high if modulating signal exceeds carrier signal
                pulse_end = min(i + round(pulse_width * n), length(t));  % Ensure pulse_end does not exceed length(t)
                pwm(i:pulse_end) = 1; % Set PWM pulse high for calculated pulse width
            end

            % Scale PWM signal by amplitude
            pwm = A * pwm;

            % Initialize PPM signal
            ppm = zeros(size(pwm));

            % Calculate PPM signal based on changes in PWM
            for i = 1:length(pwm) - round(T * n)
                if pwm(i) ~= pwm(i + 1)
                    ppm(i:i + min_width) = A; % Set PPM pulse where PWM changes
                end
            end
            ppm(1) = A;

            % Assign PPM signal to output variable
            p = ppm;
        end


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Demodulation %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % Function for generating PAM demodulation signal
        function d = pam_demodulation(~, t, m, Ts, T)
            % Calculate carrier frequency
            fc = 1/Ts;
            % Generate square wave carrier signal
            ct = square(2 * pi * fc * t, T/Ts * 100);
            % Modulate the message signal with the carrier
            mod_signal = m .* (mod(t, T) < Ts/2) .* ct;
            % Multiply with carrier again for demodulation
            demod_signal = mod_signal .* ct;
        
            % Design a Butterworth filter
            [b, a] = butter(6, 0.2);
            % Apply filtering to demodulated signal
            d = filtfilt(b, a, demod_signal);
        end


        % Function for generating flat-top PAM demodulation signal
        function d = flat_top_pam_demodulation(~, t, m, Ts, T)
            % Calculate carrier frequency
            fc = 1/Ts;
            % Generate square wave carrier signal
            ct = square(2 * pi * fc * t, T/Ts * 100);
            % Ensure only positive parts of the carrier signal
            ct(ct < 0) - 0;
            % Initialize the PAM signal array
            p = zeros(size(t));
        
            % Calculate number of samples per period for the carrier
            samplesPerPeriod = round(T * numel(t) / (t(end) - t(1) * t(2) - t(1)));
            samplesInOnPeriod =  round(Ts * numel(t) / (t(end) - t(1) + t(2) - t(1)));
        
            % Generate the flat-top PAM signal
            for i = 1:samplesPerPeriod:numel(t)
                endIndex = i + samplesInOnPeriod - 1;
                if endIndex > numel(t)
                    endIndex = numel(t);
                end
                p(i:endIndex) = m(i);
            end
        
            % Demodulate by extracting signal at pulse positions
            sampling_rate = 1/(t(2)-t(1));
            pulse_spacing = sampling_rate * Ts;
            pulse_indices = (pulse_spacing * (1:((1/Ts) * ((numel(t)/sampling_rate)) + 1)) + 1) - pulse_spacing;
            pulse_values = zeros(size(pulse_indices));
            num_pulses = size(pulse_indices);

            for i = 1:num_pulses(2)
                pulse_values(i) = p(pulse_indices(i));
            end
            pulse_indices_normalized  = pulse_indices * 1/sampling_rate;
        
            % Interpolate to get the demodulated signal
            recovered_signal_filtered = interpn(pulse_indices_normalized , pulse_values, t, "cubic");
            d = recovered_signal_filtered;
        end


        % Function for generating PWM demodulation signal
        function d = pwm_demodulation(~, t, m, Ts, A, T)
            % Calculate number of samples per period
            n = 1/(t(2) - t(1));
            step = round(Ts * n);

            % Initialize PWM signal
            pwm = zeros(1, length(t));

            % Calculate minimum and maximum pulse widths
            min_width = 0.1 * Ts;
            max_width = Ts - min_width;

            % Generate PWM signal
            for i = 1:step:length(t)
                % Calculate pulse width based on modulating signal amplitude
                pulse_width = min_width + ((max_width - min_width) * (m(i) - min(m)) / (max(m) - min(m)));
                % Set PWM pulse high if modulating signal exceeds carrier signal
                pulse_end = min(i + round(pulse_width * n), length(t));
                pwm(i:pulse_end) = 1;
            end
            pwm = A * pwm;

            % Calculate pulse postions based on changes in PWM
            ppm = zeros(size(pwm)); % Initialize PPM signal
            for i = 1:length(pwm)- round(T * n)
                if pwm(i) ~= pwm(i+1)
                    ppm(i:i+min_width) = A; % Set PPM pulse where PWM changes
                end
            end

            ppm(1) = A;
            pulse_positions = find(ppm);
            % Initialize recovered signal
            recovered_signal = zeros(size(t));

            % Iterate over pulse positions
            pulse_widths= zeros(round((size(pulse_positions)/2-1)));
            for i = 2:2:length(pulse_positions)

                % Calculate pulse width in seconds
                pulse_width_seconds = (pulse_positions(i) - pulse_positions(i-1)) * 1/n; % Convert from samples to seconds

                % Find corresponding index in the recovered signal
                index = pulse_positions(i-1);

                % Set the amplitude of the recovered signal at that index
                recovered_signal(index) = pulse_width_seconds;
                pulse_widths(i/2) = pulse_width_seconds;
            end

            % Find normalized pulse positions in the recovered signal
            pulse_indices_normalized = find(recovered_signal)*1/n;

            % Interpolate to obtain demodulated signal
            recovered_signal_filtered = interpn(pulse_indices_normalized,pulse_widths,t,"pchip");

            % Assign filtered demodulated signal to output variable
            d = recovered_signal_filtered;
        end


        % Function for generating PPM demodulation signal
        function d = ppm_demodulation(~, t, m, Ts, A, T)
            % Calculate sampling rate
            n = 1/(t(2) - t(1));

            % Determine step size for PWM modulation
            step = round(Ts * n);

            % Calculate minimum and maximum pulse widths
            min_width = 0.1 * Ts;
            max_width = Ts - min_width;

            % Initialize PWM signal
            pwm = zeros(1, length(t));

            % Perform PWM modulation
            for i = 1:step:length(t)
                % Calculate pulse width based on modulating signal amplitude
                pulse_width = min_width + ((max_width - min_width) * (m(i) - min(m)) / (max(m) - min(m)));

                % Set PWM pulse high if modulating signal exceeds carrier signal
                pulse_end = min(i + round(pulse_width * n), length(t));
                pwm(i:pulse_end) = 1;
            end

            % Scale PWM signal by amplitude
            pwm = A * pwm;

            % Initialize PPM signal
            ppm = zeros(size(pwm));

            % Calculate PPM signal based on changes in PWM
            for i = 1:length(pwm) - round(T * n)
                if pwm(i) ~= pwm(i+1)
                    ppm(i:i+min_width) = A;
                end
            end
            ppm(1) = A;

            % Find pulse positions in PPM signal
            pulse_positions = find(ppm);

            % Initialize recovered signal and pulse widths
            recovered_signal = zeros(size(t));
            pulse_widths = zeros(round((size(pulse_positions)/2 - 1)));
            % Iterate over pulse positions
            for i = 2:2:length(pulse_positions)
                % Calculate pulse width in seconds
                pulse_width_seconds = (pulse_positions(i) - pulse_positions(i-1)) * 1/n;

                % Find corresponding index in the recovered signal
                index = pulse_positions(i-1);

                % Set the amplitude of the recovered signal at that index
                recovered_signal(index) = pulse_width_seconds;
                pulse_widths(i/2) = pulse_width_seconds;
            end

            % Find normalized pulse positions in the recovered signal
            pulse_indices_normalized = find(recovered_signal)*1/n;

            % Interpolate to obtain demodulated signal
            recovered_signal_filtered = interpn(pulse_indices_normalized,pulse_widths,t,"pchip");

            % Assign filtered demodulated signal to output variable
            d = recovered_signal_filtered;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Code executed when plot button is clicked in a GUI application.

            % Retrieve user input values from GUI elements.
            A = app.AmplitudeAEditField.Value;             % Amplitude
            T = app.PulsetimeTEditField.Value;             % Pulse width
            Ts = app.SamplingTimeTsEditField.Value;        % Sampling time
            modulation_type = app.ModulationSchemaDropDown.Value; % Modulation type

            % Retrieve user input vectors for time and message signal.
            NO_intervals = app.NoIntervalsEditField.Value; % Number of intervals
            t_vector = app.TimeVectorEditField.Value;      % Time vector
            m_vector = app.MessageSignalmtEditField.Value; % Message signal vector

            % Generate time and message signal arrays for each interval.
            tArray = [];    % Initialize array for time
            mArray = [];    % Initialize array for message signal
            t = 0;          % Initialize time variable
            for i = 1:NO_intervals
                t_strSplit = strsplit(t_vector, ',');     % Split time vector
                t = eval(t_strSplit{i});                  % Evaluate time expression
                m_strSplit = strsplit(m_vector, ',');     % Split message signal vector
                m = eval(m_strSplit{i});                  % Evaluate message signal expression
                tArray = [tArray, t];                     % Concatenate time arrays
                mArray = [mArray, m];                     % Concatenate message signal arrays
            end

            % Calculate carrier signal.
            fc = 1/Ts;                                      % Carrier frequency
            ct = A * square(2 * pi * fc * tArray, T/Ts * 100); % Carrier signal

            % Generate modulated signal based on modulation type.
            switch modulation_type
                case 'PAM'
                    p = app.pam_modulation(tArray, mArray, Ts, T); % Generate PAM modulated signal
                    d = app.pam_demodulation(tArray, mArray, Ts, T); % Demodulate PAM signal
                case 'flat top PAM'
                    p = app.flat_top_pam_modulation(tArray, mArray, Ts, T); % Generate flat-top PAM modulated signal
                    d = app.flat_top_pam_demodulation(tArray, mArray, Ts, T); % Demodulate flat-top PAM signal
                case 'PWM'
                    p = app.pwm_modulation(tArray, mArray, A, Ts); % Generate PWM modulated signal
                    d = app.pwm_demodulation(tArray, mArray, Ts, A, T); % Demodulate PWM signal
                case 'PPM'
                    p = app.ppm_modulation(tArray, mArray, Ts, A, T); % Generate PPM modulated signal
                    d = app.ppm_demodulation(tArray, mArray, Ts, A, T); % Demodulate PPM signal
                otherwise
                    disp('Invalid modulation type'); % Display error message for invalid modulation type
                    return; % Exit function
            end

            % Plotting

            % Message Signal subplot
            subplot(4,1,1);
            plot(tArray, mArray);
            title('Message Signal');
            xlabel('Time');
            ylabel('Amplitude');

            % Carrier Signal subplot
            subplot(4,1,2);
            plot(tArray, ct);
            title('Carrier Signal');
            xlabel('Time');
            ylabel('Amplitude');

            % Modulated Signal subplot
            subplot(4,1,3);
            plot(tArray, p);
            title('Modulated Signal');
            xlabel('Time');
            ylabel('Amplitude');

            % Demodulated Signal subplot
            subplot(4,1,4);
            plot(tArray, d);
            title('Demodulated Signal');
            xlabel('Time');
            ylabel('Amplitude');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.MessageSignalmtEditFieldLabel = uilabel(app.UIFigure);
            app.MessageSignalmtEditFieldLabel.Text = 'Message Signal m(t)';
            app.MessageSignalmtEditField = uieditfield(app.UIFigure, 'text');
            app.MessageSignalmtEditField.Value = '3* cos(4*pi*t),6*t';
            app.AmplitudeAEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeAEditFieldLabel.Text = 'Amplitude (A)';
            app.AmplitudeAEditField = uieditfield(app.UIFigure, 'numeric');
            app.AmplitudeAEditField.Value = 2;
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = 'Pulse time (T)';
            app.PulsetimeTEditField = uieditfield(app.UIFigure, 'numeric');
            app.PulsetimeTEditField.Value = 0.1;
            app.ModulationSchemaDropDownLabel = uilabel(app.UIFigure);
            app.ModulationSchemaDropDownLabel.Text = 'Modulation Schema';
            app.ModulationSchemaDropDown = uidropdown(app.UIFigure);
            app.ModulationSchemaDropDown.Items = {'PWM', 'PPM', 'PAM', 'flat top PAM'};
            app.ModulationSchemaDropDown.Placeholder = 'Choose';
            app.ModulationSchemaDropDown.Value = 'PWM';
            app.SamplingTimeTsLabel = uilabel(app.UIFigure);
            app.SamplingTimeTsLabel.Text = 'Sampling Time (Ts)';
            app.SamplingTimeTsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingTimeTsEditField.Value = 0.125;
            app.TimeVectorEditFieldLabel = uilabel(app.UIFigure);
            app.TimeVectorEditFieldLabel.Text = 'Time Vector';
            app.TimeVectorEditField = uieditfield(app.UIFigure, 'text');
            app.TimeVectorEditField.Value = '0:0.001:1,1:0.001:3';
            app.NoIntervalsEditFieldLabel = uilabel(app.UIFigure);
            app.NoIntervalsEditFieldLabel.Text = 'No. Intervals';
            app.NoIntervalsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoIntervalsEditField.Limits = [1 Inf];
            app.NoIntervalsEditField.Value = 2;
            app.PulseModulationGUILabel = uilabel(app.UIFigure);
            app.PulseModulationGUILabel.Text = 'Pulse Modulation GUI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pulse_Modulation_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end